classdef SignalProcessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        StatusLabel              matlab.ui.control.Label
        ExportAudioButton        matlab.ui.control.Button
        TabGroup                 matlab.ui.container.TabGroup
        AudioFileTab             matlab.ui.container.Tab
        GridLayout6              matlab.ui.container.GridLayout
        FileInformationPanel     matlab.ui.container.Panel
        GridLayout2_2            matlab.ui.container.GridLayout
        DurationDisplay          matlab.ui.control.Label
        SampleRateDisplay        matlab.ui.control.Label
        ChannelNumberDisplay     matlab.ui.control.Label
        FilePathDisplay          matlab.ui.control.Label
        DurationLabel            matlab.ui.control.Label
        SampleRateLabel          matlab.ui.control.Label
        NumberofChannelsLabel    matlab.ui.control.Label
        FilePathLabel            matlab.ui.control.Label
        ProcessedWaveform        matlab.ui.control.UIAxes
        ProcessedFreqSpectrum    matlab.ui.control.UIAxes
        InitialFreqSpectrum      matlab.ui.control.UIAxes
        InitialWaveform          matlab.ui.control.UIAxes
        FilterAttributesTab      matlab.ui.container.Tab
        GridLayout7              matlab.ui.container.GridLayout
        FilterInformationPanel   matlab.ui.container.Panel
        GridLayout2_3            matlab.ui.container.GridLayout
        WindowFunctionDisplay    matlab.ui.control.Label
        WindowSizeDisplay        matlab.ui.control.Label
        CutoffFrequencyDisplay   matlab.ui.control.Label
        FilterTypeDisplay        matlab.ui.control.Label
        FilterTypeLabel_2        matlab.ui.control.Label
        WindowFunctionLabel_2    matlab.ui.control.Label
        WindowSizeLabel_2        matlab.ui.control.Label
        CutoffFrequencyLabel     matlab.ui.control.Label
        BasicFilterResponse      matlab.ui.control.UIAxes
        FilterCoefficients       matlab.ui.control.UIAxes
        PhaseResponse            matlab.ui.control.UIAxes
        AmplitudeResponseLarge   matlab.ui.control.UIAxes
        RealTimeMonitoringTab    matlab.ui.container.Tab
        GridLayout7_2            matlab.ui.container.GridLayout
        InformationPanel         matlab.ui.container.Panel
        GridLayout8              matlab.ui.container.GridLayout
        TheLissajousplotshowsthephasedifferencebetweentwochannelsLabel  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        BufferSizeDropDown       matlab.ui.control.DropDown
        BufferSizeDropDownLabel  matlab.ui.control.Label
        LissajousFigure          matlab.ui.control.UIAxes
        RTFreqSpectrum           matlab.ui.control.UIAxes
        FilterPanel              matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        BandwidthSlider          matlab.ui.control.Slider
        FrequencySlider          matlab.ui.control.Slider
        ApplyFilterButton        matlab.ui.control.Button
        WindowSizeSlider         matlab.ui.control.Slider
        WindowFunctionDropdown   matlab.ui.control.DropDown
        WindowFunctionLabel      matlab.ui.control.Label
        WindowSizeField          matlab.ui.control.NumericEditField
        FilterTypeDropdown       matlab.ui.control.DropDown
        BandwidthField           matlab.ui.control.NumericEditField
        BandwidthHzLabel         matlab.ui.control.Label
        FilterTypeLabel          matlab.ui.control.Label
        WindowSizeLabel          matlab.ui.control.Label
        FrequencyField           matlab.ui.control.NumericEditField
        CutoffFrequencyHzLabel   matlab.ui.control.Label
        AmplitudeResponse        matlab.ui.control.UIAxes
        PlaybackPanel            matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        ToggleFilterButton       matlab.ui.control.StateButton
        SignalType               matlab.ui.control.Label
        CurrentlyPlayingLabel    matlab.ui.control.Label
        PlayButton               matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        timeSlider               matlab.ui.control.Slider
        SignalProcessorLabel     matlab.ui.control.Label
        ImportFileButton         matlab.ui.control.Button
    end

    
    properties (Access = private)

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %                                                                 %
        %                       Signal Processor V1.0                     %
        %                          Alex Soca, 2023                        %
        %                                                                 %
        %                    +-------------------------+                  %
        %                    |                         |                  %
        %                    |       ###               |                  %
        %                    |     #     #             |                  %
        %                    |    #       #       #    |                  %
        %                    |             #     #     |                  %
        %                    |               ###       |                  %
        %                    |                         |                  %
        %                    +-------------------------+                  %
        %                                                                 %
        %                                                                 %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % These variables are declared here as multiple functions and 
        % callbacks use them so storing them in a place where they are 
        % accessible directly was a more efficient solution compared to 
        % recalculating them in each function.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        path = ''; % Initialises variable to store file path string
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % The audioread function will read the path of the file when it is
        % imported. It is stored here so that the path can be displayed in
        % multiple text boxes within the GUI.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        y = []; % Initialises the matrix storing the amplitudes of the samples in the imported audio file
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Once imported, the data from the audio file is stored in y with
        % each column assigned to an individual channel. Each row contains
        % a sample at index n corrosponding to the amplitude of the wave at
        % this time. Mono audio is stored as a vector.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        yProcessed = []; % Initialises the matrix storing the amplitudes of the samples calculated from the convolution
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % The audiowrite function requires that the audio file has channels
        % separated into columns so the yProcessed matrix should have the
        % same dimensions as y prior to export.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        fs = 44100; % Initialises the variable storing the sample rate of the file
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % The processed audio file will have the same sample rate as the
        % initial audio file.
        % The standard CD Quality sample rate is 44.1kHz. The audioread
        % function will overwrite this value in the case of the imported
        % file having a different sample rate.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        t = []; % Initialises the vector storing the time data for each sample
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % When the file is imported, the samples are indexed by their
        % row number in the matrix, however when plotted this must be
        % converted into its corrosponding time. This is reliant upon the
        % sample rate and so the time vector is generated after import.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        duration = 0; % Initialises the variable storing the duration of the audio file
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % The duration is calculated by using the formula 
        % [number of rows] / [sample rate]
        % The value obtained is displayed in the file information panel
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        channelNum = 1; % Initialises the variable storing the number of channels of audio in the audio file
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % The number of channels corrosponds to the number of columns in
        % the matrix y, however storing it as a separate variable improves
        % the readability of the code as this is used frequently throughout
        % the program.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        xFreq = []; % Initialises the vector storing the range of frequencies to plot on x axis of the figures corrosponding to the fourier transform of the file
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Similarly to the time variable, the fft function generates an
        % array of amplitudes which need to be assigned their corrosponding
        % frequencies dependant on their position within the matrix.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        ws = 35; % Initialises the variable storing the user selected window size
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Sets the initial window size (the number of samples included in
        % each multiplication within the convolution). A larger window size
        % increases the Q of the filter (making the amplitude response
        % sharper, closer to the ideal filter response) but also increases
        % the phase shift and takes longer to process due to the added
        % complexity in the convolution step.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        wType = 'Hanning'; % Initialises the variable storing the type of window function selected by the user
        filterType = 'Low Pass'; % Initialises the variable storing the type of filter selected by the user
        fcUser = 10000; % Initialises variable storing the cutoff frequency selected by the user
        bandwidth = 100; % Initialises variable storing the filter bandwidth selected by the user
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Sets the initial filter settings. These are stored as variables
        % rather than referencing the values of the sliders and dropdowns
        % directly to improve readability.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        n = []; % Initialises the vector storing the discrete time indices
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % In this program, the discrete time index is used to generate the
        % basic filter response vector. An example n vector with
        % window size 7 is as follows :
        %                       [ 3 2 1 0 1 2 3 ]
        % Where 0 is the central value and the vector is symmetrical. When
        % n = 0, a different formula is used to generate the value at this
        % point and so logical indexing is used to efficiently apply the
        % calculations in the basic filter response generation function.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        w = []; % Initialises the vector storing the window function
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % The window function alters the shape of the curve and the phase
        % response as it is used to calculate the filter coefficients.
        % Some window functions affect phase less however allow some
        % frequencies beyond the cutoff to pass as they do not attenuate
        % the side lobe(s) (areas where the amplitude response "bounces" 
        % caused by limiting the infinite impulse response to a finite 
        % region) as aggressively.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        hd = []; % Initialises the basic filter response vector
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Basic filter response is used to calculate the filter
        % coefficients. It is dependant on the cutoff frequency (which must
        % be normalised) which is then converted into an angular frequency
        % and the discrete time index.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        h = []; % Initialises the filter coefficient vector
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Filter coefficients are the final product of the filter
        % generation process and are what is used in the convolution to
        % apply the desired filter to the audio signal. It will be a vector
        % of length [window size]. In order to keep the gain at unity, the
        % filter coefficient vector is normalised by multiplying each value
        % by 1/[largest coefficient]. Additionally, for audio files with
        % more than 1 channel, the vector is copied into multiple rows in
        % a separate matrix to apply the convolution in a single pass 
        % rather than looping convolution for each channel.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        H = []; % Initialises the vector of y values for the amplitude response of the generated filter
        wResponse = []; % Initialises the vector of x values for the amplitude response of the generated filter
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % These values are calculated by the use of the freqz function.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        yAmpInit = []; % Initialises the matrix of amplitudes of frequencies in the unprocessed audio file for plotting
        yAmpProcessed = []; % Initialises the matrix of amplitudes of frequencies in the processed sample matrix for plotting
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % The time to frequency function will calculate the values written
        % to these matrices with each column containing the fourier
        % transform data (processed to remove symmetry) of each channel.
        % The result is a matrix with each row containing amplitude data
        % and the corrosponding frequency being found by :
        %    ([sample rate] * [index]) / [length of audio file matrix]
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        legendText = []; % Initialises vector storing the names of each line on the plot for the legend
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % For audio files with 1 file, the legend text is "Channel 1". For
        % stereo audio files, the first channel is labelled as 
        % "Left Channel"and the second channel is labelled as "Right 
        % Channel". For audio files with more than 2 channels, the channels 
        % are named as "Channel n" where n is the channel number.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        stopAll = 1; % Sets the stop variable to 1
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % MATLAB Does not have a global stop callback function so this
        % binary check must be utilised to cancel any currently
        % processing callbacks. This variable is checked in the play button
        % call back at every iteration.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        yRTAmpInit = []; % Initialises matrix storing the fourier transforms of the buffers for the mono initial audio
        yRTAmpProcessed = []; % Initialises matrix storing the fourier transforms of the buffers for the mono processed audio
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % These matrices store the fourier transforms of buffer size
        % sections of their respective audio files. Each buffer's fourier
        % transform is stored in a new row with the column containing the
        % amplitude data. The matrices are iterated through for the real
        % time plots. In order to optimise this process, the channels are
        % summed into mono and normalised prior to the fft so that fewer 
        % plots are required per iteration, improving performance at the 
        % cost of no multichannel information.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        timeCode = 1; % Sets the sample index to 1 (MATLAB indexing begins at 1)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % The time code stores the current sample being plotted at
        % playback. This is used to retain the point the audio was paused
        % at when the user resumes for both the audio and the plotted
        % figures.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        

        % The buffer size is only used for real time audio monitoring
        bufferSize = 4096; % Sets the number of samples to be transformed via discrete fourier transform per iteration. A higher number means the transform will be taken from a larger time period so will be a more averaged representation of the sample, however a lower buffer size will give a more precise representation of the frequencies present at a given point in time of a signal which changes often and quickly
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % The minimum buffer size can be obtained by using the formula : 
        %      [frequency] = [sample rate] / [number of samples]
        % to find the minimum frequency required. The minimum frequency
        % humans can hear is 20hz. Using a standard 44.1k audio file,
        % the minimum buffer size is >2048 (21.5Hz). For best results,
        % however, the buffer size should be 2-3 times as large as the
        % smallest frequency. As a result, the default buffer size is set
        % to 4096 samples. A larger buffer size is less ideal as fewer
        % frames are generated so the plot is less fluid, however a more
        % accurate plot with more datapoints may be more advantageous than 
        % inaccurate realtime data. Larger buffer sizes increase
        % performance during playback.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    end
        
    methods (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %                                                                 %
        %                          Functions                              %
        %                                                                 %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function [] = windowFunctionGenerator(app) % Function to generate a window function vector
            nRHS = 1:(app.ws-1)/2; % Creates a vector from 1 to one less than half the window size

            app.n = [fliplr(nRHS) 0 nRHS]; % Constructs the discrete time index array from the ascending number vector generated previously (nRHS)

            switch app.wType % Depending on the number input by the user, one of the following options will be executed
                case 'Hanning' % If the user selected Hanning, the following code will execute
                    % Hanning Function
                    app.w = 0.5 + 0.5*cos((2*pi*app.n)/app.ws); % Creates the Hanning window function
                case 'Hamming' % If the user selected Hamming, the following code will execute
                    % Hamming Function
                    app.w = 0.54 + 0.46*cos((2*pi*app.n)/app.ws); % Creates the Hamming window function
                case 'Blackman' % If the user selected Blackman, the following code will execute
                    % Blackman Function
                    app.w = 0.42 + 0.5*cos((2*pi*app.n)/(app.ws-1)); % Creates the Blackman window function
            end % Ends the switch statement
        end % Ends the function

        function [] = basicFilterResponseGenerator(app) % Function to generate a basic filter response vector
            app.hd = []; % Initialises the basic filter response vector (if the user performs more than one filter application with different filter sizes, the program will crash unless this is reinitialised)
            fc = app.fcUser/app.fs; % Normalises the cutoff frequency
            wc = 2*pi*fc; % Calculates the angular cutoff frequency and stores it as a variable
            
            switch app.filterType % Depending on the number the user enters, the code corrosponding to this number will run
                case 'Low Pass' % If Low Pass is selected, the following code will execute
                    % Low pass filter
                    app.hd(app.n~=0) = 2*fc*(sin(app.n(app.n~=0)*wc)./(app.n(app.n~=0)*wc)); % Generates the basic filter response array when the value for discrete time index is not 0
                    app.hd(app.n==0) = 2*fc; % Generates the basic filter response value when the discrete time index is 0
                case 'High Pass' % If High Pass is selected, the following code will execute
                    % High pass filter
                    app.hd(app.n~=0) = -2*fc*(sin(app.n(app.n~=0)*wc)./(app.n(app.n~=0)*wc)); % Generates the basic filter response array when the value for discrete time index is not 0
                    app.hd(app.n==0) = 1-2*fc; % Generates the basic filter response value when the discrete time index is 0
                case 'Band Pass' % If Band Pass is selected, the following code will execute
                    % Band pass filter
                    [fc1, fc2, wc1, wc2] = findCutoff(app, fc); % Finds the lower and upper cutoff frequencies around the central selected cutoff frequency
                    app.hd(app.n~=0) = 2*fc2*(sin(app.n(app.n~=0)*wc2)./(app.n(app.n~=0)*wc2))-2*fc1*(sin(app.n(app.n~=0)*wc1)./(app.n(app.n~=0)*wc1)); % Generates the basic filter response array when the value for discrete time index is not 0
                    app.hd(app.n==0) = 2*(fc2-fc1); % Generates the basic filter response value when the discrete time index is 0
                case 'Band Stop' % If Band Stop is selected, the following code will execute
                    % Band stop filter
                    [fc1, fc2, wc1, wc2] = findCutoff(app, fc); % Finds the lower and upper cutoff frequencies around the central selected cutoff frequency
                    app.hd(app.n~=0) = 2*fc1*(sin(app.n(app.n~=0)*wc1)./(app.n(app.n~=0)*wc1))-2*fc2*(sin(app.n(app.n~=0)*wc2)./(app.n(app.n~=0)*wc2)); % Generates the basic filter response array when the value for discrete time index is not 0
                    app.hd(app.n==0) = 1-2*(fc2-fc1); % Generates the basic filter response value when the discrete time index is 0
            end % Ends the switch statement
        end % Ends the function

        function [fc1, fc2, wc1, wc2] = findCutoff(app, fc) % Declares function to request the bandwidth of the BP/BS filter from the user and calculates the cutoff frequencies required
                fc1 = fc-(app.bandwidth/app.fs)/2; % Finds the lower cutoff frequency
                wc1 = 2*pi*fc1; % Calculates the lower angular cutoff frequency
                fc2 = fc+(app.bandwidth/app.fs)/2; % Finds the upper cutoff frequency
                wc2 = 2*pi*fc2; % Calculates the upper angular cutoff frequency
         end % Ends the function

         function [] = filterCoefficientGenerator(app) % Declares function to generate a vector of filter coefficients used in the convolution of the audio file
             app.h = app.w.*app.hd; % Generates the filter coefficient array from the window function and basic filter response

             app.h = app.h/sum(abs(app.h)); % Normalises filter coefficients so that gain remains at unity
         end % Ends the function


         function [] = filterResponseGenerator(app) % Declares function to calculate the amplitude response from the filter coefficients
             [app.H, app.wResponse] = freqz(app.h); % Calculates the amplitude response of the filter

             app.wResponse = app.wResponse/(2*pi)*app.fs; % Converts from rad/sample to Hz for plot
         end % Ends the function

         function [] = applyFilter(app) % Declares function to convolute the audio file using the filter coefficients previously calculated and create a new matrix of amplitudes ready for export
             app.yProcessed = zeros(length(app.y), app.channelNum); % Initialises the array of y values in the output to increase efficiency by preallocating the memory

             hExtended = app.h.'; % Flips the rows and columns of the filter coefficient vector so that the element-wise multiplication results in the correct dimensions for the audiowrite function to process later, this step saves having to reshape the entire final matrix which would take more processing time
            
             for i = 1:app.channelNum % For the number of channels, the following code will execute
                 hExtended(:, i) = hExtended(:,1); % Duplicates the values in the first column to a new column 
             end % Ends the for loop
            
             for i = 1:length(app.y)-app.ws+1 % For one more than the number of elements in y less the window size, the following code will execute
                 app.yProcessed(i:i+app.ws-1,:) = app.yProcessed(i:i+app.ws-1,:) + hExtended.*app.y(i+app.ws-1,:); % Slides the window over one channel of the imported audio and multiplies the values in the coefficient vector and amplitude vector element wise, summing the results
             end % Ends the nested for loop
             
             app.ExportAudioButton.Enable = "on"; % Enables the export audio button
         end % Ends the function

         function [yAmp] = timeToFrequency(app, y) % Declares function to convert the time domain to frequency domain by using a discrete fourier transform
             yAmp = zeros(size(y)); % Initialises the array of amplitudes of each frequency for the audio
             for c = 1:app.channelNum % For the number of channels of audio, the following code will execute
                 yAmp(:,c) = abs(fft(y(:, c))); % Finds the real numbers in the discrete fourier transform of the initial file and stores them in an array
             end % Ends the for loop

             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             % Due to the symmetrical pattern formed from the real numbers 
             % in the fft, the frequency domain range must be halved. This also
             % means that the amplitudes of each frequency must be doubled
             % as the total energy was distributed across the whole spectrum.
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

             yAmp = yAmp(1:floor(length(yAmp)/2), :)*2; % Halves the length of the array to remove the symmetrical points and doubles the amplitudes to redistribute the energy from the removed points
         end

         function [] = updateFilterFigures(app) % Declares function to plot filter figures
             plot(app.AmplitudeResponse, app.wResponse, abs(app.H), LineWidth=2); % Plots the amplitude response of the generated filter
             app.AmplitudeResponse.YLim = [0,1]; % Limits the y axis of the plot so that it does not change between filter alterations
             plot(app.AmplitudeResponseLarge, app.wResponse, abs(app.H), LineWidth=2); % Plots the amplitude response of the generated filter on the enlarged axes
             app.AmplitudeResponseLarge.YLim = [0,1]; % Limits the y axis of the plot so that it does not change between filter alterations
             plot(app.PhaseResponse, app.wResponse, angle(app.H), LineWidth=2); % Plots the phase response of the generated filter
             stem(app.BasicFilterResponse, app.hd); % Plots the basic filter response
             app.BasicFilterResponse.XLim = [1 app.ws]; % Limits the x axis of basic filter response plot
             stem(app.FilterCoefficients, app.h); % Plots the filter coefficients
             app.FilterCoefficients.XLim = [1 app.ws]; % Limits the x axis of filter coefficients plot
         end % Ends the function

         function [] = disableWhileProcessing(app, state) % Declares function to disable UI elements while processing so that the user cannot change values as the filter is being applied preventing crashes
             state = ~state; % As state is taken as 1 for disable when the function is called, this must be inverted to 0 apply the disabled attribute to the UI element
             % Disables or enables the following elements depending on
             % the value taken by the function when called :
             app.ApplyFilterButton.Enable = state;
             app.ImportFileButton.Enable = state;
             app.ExportAudioButton.Enable = state;
             app.FrequencySlider.Enable = state;
             app.FrequencyField.Enable = state;
             app.FilterTypeDropdown.Enable = state;
             app.WindowFunctionDropdown.Enable = state;
             app.WindowSizeSlider.Enable = state;
             app.WindowSizeField.Enable = state;
             app.timeSlider.Enable = state;
             app.PlayButton.Enable = state;
             app.StopButton.Enable = state;
        end % Ends function

        function [] = lissajousPlotter(app, sample, y) % Declares function to plot a lissijous figure of bufferSize samples from the specified sample index
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Lissajous plots are useful in audio production to visualise
            % and identify the phase difference between audio channels.
            % When the audio is completely in phase, the result will be a
            % line of y = x. This is always the case for mono audio as
            % there is only 1 channel, hence why the plot below is
            % simplified to a line when only 1 channel is present to reduce
            % processor usage. When the left channel sample is different to
            % the right channel sample, the plotted point deviates from the
            % central y = x line in the direction of whichever channel's
            % magnitude is larger. The more out of phase data present, the
            % more circular the plot will become. Since the filters
            % generated in this case affect the phase of the audio, this
            % feature may be used to visualise the affect it has.
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if app.channelNum >=2 % If the audio file imported has 2 or more channels, the following code will execute
                s = scatter(app.LissajousFigure, y(sample:sample+app.bufferSize,1), y(sample:sample+app.bufferSize,2)); % Plots the samples from channel 1 on the y axis and the samples from channel 2 on the x axis
                s.Marker = "."; % Sets the marker to a point
                s.MarkerEdgeColor = 'black'; % Sets the colour to black
            else % If the audio has 1 channel, the following code will execute
                plot(app.LissajousFigure, [-y(sample), y(sample)], [-y(sample), y(sample)], 'color', "black", LineWidth=2); % Plots the magnitude of the current sample on the line y = x
            end % Ends the if statement
            xlim(app.LissajousFigure, [-max(y(:)), max(y(:))]); % Sets the x axis limit to the maximum amplitude to stop auto scaling between frames
            ylim(app.LissajousFigure, [-max(y(:)), max(y(:))]); % Sets the y axis limit to the maximum amplitude to stop auto scaling between frames
        end % Ends the function

        function [analysedMatrix] = frequencyBufferAnalysis(app, y) % Declares function to analyse each buffer in the audio file in preperation for the real time plot   
            analysedMatrix = zeros(ceil(length(y)/app.bufferSize), app.bufferSize); % Preallocates memory for matrix to improve efficiency
            y = sum(y,2); % Combines all channels into a mono channel
            y = y*1/max(y); % Normalises the product as the sum of channels may be greater than 1 and clip
            for i = 1:app.bufferSize:length(y)-app.bufferSize % For the number of buffers in the audio file, the following code will execute
                analysedMatrix(floor(i/app.bufferSize)+1,:) = abs(fft(y(i:i+app.bufferSize-1))); % Calculates the fft of the buffer and stores it in the matrix
            end % Ends the for loop
            analysedMatrix = analysedMatrix(:,1:size(analysedMatrix,2)/2)*2; % Halves the range of the transforms to remove symmetry and doubles the amplitude to reflect the energy lost
        end % Ends the function
        
        function [] = realTimeFreqPlotter(app, sample, y) % Declares function to plot the real time frequency response of the audio playing
            plot(app.RTFreqSpectrum, linspace(0,20000, app.bufferSize/2), y(floor(sample/app.bufferSize+1),:), 'color', 'black', LineWidth=2); % Plots the current buffer's frequency response curve for the selected audio file
            ylim(app.RTFreqSpectrum, [0, max(reshape(y,1,numel(y)))]); % Sets the y axis limit to the maximum amplitude to prevent scaling between frames
            xlim(app.RTFreqSpectrum, [0, 20000]); % Sets the x axis limit to the range of frequencies to be plotted to prevent scaling between frames
        end % Ends the function
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
        % If another file is imported, these parameters must be reset
        % otherwise playback will crash the program if the previous file
        % has a different number of samples.
        app.timeCode = 1; % Resets the variable storing the index of the sample last played
        app.ToggleFilterButton.Value = 0; % Sets the toggle filter button state to off
        app.SignalType.Text = "Unfiltered Signal"; % Sets the signal type display text
        app.ToggleFilterButton.Enable = "off"; % Disables the toggle filter button
            
        app.StatusLabel.Text = "Selecting file..."; % Updates the status display
        app.ImportFileButton.Enable = 'off'; % Disables button so that multiple simultaneous file imports are not possible
        drawnow; % Forces the app to render the button as disabled

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % MATLAB Does not always render changes in button visibility or 
        % changes in the text for text boxes while callbacks are processing 
        % which is why throughout this program drawnow will be used to force 
        % the render engine to display these changes immediately.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % If the user closes the window instead of selecting a file, the 
        % program will crash which is why a try catch function is used.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        try % Attempts the following code
            [file,app.path] = uigetfile('*.wav','Select a .wav file'); % Prompts user to select a file for input and stores the filename and path as variables
            fullPath = append(app.path, file); % Converts the filename and path to a single string to be read by the audioread function
            app.StatusLabel.Text = "Importing audio..."; % Updates the status display
            drawnow; % Renders the change in text
            [app.y, app.fs] = audioread(fullPath); % Creates a matrix storing the amplitude data for the audio file and a variable to store the sample rate of the file
            app.t = (0:1:length(app.y)-1)/app.fs; % Creates an array of the time each sample is located
            app.duration = length(app.y)/app.fs; % Stores the duration of the file in seconds in a variable
            app.channelNum = size(app.y,2); % Stores the number of channels of audio in the file in a variable
            app.xFreq = 0:(app.fs/length(app.y)):(app.fs/2-app.fs/length(app.y)); % Generates an array of frequencies
            app.yAmpInit = timeToFrequency(app, app.y); % Calls the function to find the frequency spectrum of the imported audio
            
            plot(app.InitialWaveform, app.t, app.y); % Plots the initial amplitudes over time of the imported file (Time domain)

            app.legendText = strings(app.channelNum, 1); % Initialises the legend text vector to increase efficiency by preallocating memory
            for i = 1:app.channelNum % The following code will execute for the number of channels in the file
                if i == 1 && app.channelNum ~=  1 % If the current channel is 1 and there are more than 1 channels, the following code will execute
                    app.legendText(i) = "Left Channel"; % Sets the legend text for the first line to left channel
                elseif i == 2 % If the current channel is 2, the following code will execute
                    app.legendText(i) = "Right Channel"; % Sets the legend text for the second line to right channel
                else % Otherwise the following code will execute
                    app.legendText(i) = append("Channel ", num2str(i)); % Sets the legend text for the current line to its channel number
                end % Ends the if statement
            end % Ends the for loop
            legend(app.InitialWaveform, app.legendText); % Adds a legend to the initial waveform plot

            plot(app.InitialFreqSpectrum, app.xFreq, app.yAmpInit, LineWidth=2); % Plots the initial frequency specrum of the imported file (Frequency domain)
            legend(app.InitialFreqSpectrum, app.legendText); % Updates the legend with the labels corrosponding to the lines plotted
            xlim(app.InitialFreqSpectrum, [0, 20000]); % Limits the range of the x axis

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % xlim justification : The maximum range of frequencies humans 
            % can detect is from 20-20000Hz, therefore plotting beyond 
            % these values is not necessary.
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.FilePathDisplay.Text = fullPath; % Updates file path in the audio file information panel
            app.ChannelNumberDisplay.Text = num2str(app.channelNum); % Updates the number of channels in the audio file information panel
            app.SampleRateDisplay.Text = [num2str(app.fs), 'Hz']; % Updates the sample rate in the audio file information panel
            app.DurationDisplay.Text = [num2str(app.duration), 's']; % Updates the duration in the audio file information panel
            app.disableWhileProcessing(0); % Calls function to enable the buttons
            app.StatusLabel.Text = "Import successful!"; % Updates the status display
            app.ImportFileButton.Enable = 'on'; % Re-enables button
            drawnow; % Forces the app to render the button as enabled
            app.timeSlider.Limits = [0, app.duration]; % Sets the time slider limits to time of the audio file
            app.timeSlider.MinorTicks = 0:app.duration/100:app.duration; % Appropriately scales the minor ticks to the new limits
            app.timeSlider.MajorTicks = 0:app.duration/10:app.duration; % Appropriately scales the major ticks to the new limits
            pause(2); % Waits 2 seconds
            text = append("Ready to process ", file); % Assigns the text to be displayed to a variable
            app.StatusLabel.Text = text; % Updates the status display with the name of the file
        catch % If the code produces an error, the following will execute
            app.StatusLabel.Text = "Import failed, Please try again"; % Updates the status display
            app.ImportFileButton.Enable = 'on'; % Re-enables button
            drawnow; % Forces the app to render the button as enabled
            pause(5); % Waits 5 seconds
            app.StatusLabel.Text = "Import a file"; % Updates the status display
        end % Ends the try statement
        
        end

        % Button pushed function: ExportAudioButton
        function ExportAudioButtonPushed(app, event)
            app.StatusLabel.Text = "Exporting Audio..."; % Updates the status display
            drawnow; % Renders the change in text
            audiowrite("Processed.wav", app.yProcessed, app.fs); % Writes the array of amplitudes to an audio file
            text = append("Audio Exported to : ", app.path, "Processed.wav"); % Assigns the text to be displayed to a variable
            app.StatusLabel.Text = text; % Updates the status display
            drawnow; % Renders the change in text
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            app.disableWhileProcessing(1); % Disables the UI elements that would allow the user to change variable values while processing
            app.ToggleFilterButton.Value = 0; % Sets the toggle filter button state to off
            app.SignalType.Text = "Unfiltered Signal"; % Sets the signal type display text
            app.ToggleFilterButton.Enable = 'off'; % Disables the toggle filter button
            app.StatusLabel.Text = "Generating Filter..."; % Updates the status display
            drawnow; % Renders the change in text and the state changes of the UI elements
            
            
            windowFunctionGenerator(app); % Calls the script used to generate the window function vector
            basicFilterResponseGenerator(app); % Calls the script used to generate the basic filter response vector
            filterCoefficientGenerator(app); % Calls the script used to generate the array of filter coefficients
            filterResponseGenerator(app); % Calls the script used to find the filter response using the coefficients generated previously

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Calculates all necessary values in order to colvolute the 
            % audio matrix by calling the above functions before performing 
            % the convolution with the applyFilter function
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.StatusLabel.Text = "Applying Filter..."; % Updates the status display
            drawnow; % Renders the change in text

            applyFilter(app); % Calls the script used to convolute the imported audio and output a filtered matrix
            
            %plot(app.AmplitudeResponse, app.wResponse, abs(app.H)); % Plots the amplitude response of the generated filter

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Analyses the processed audio and plots the results
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.StatusLabel.Text = "Analysing Audio..."; % Updates the status display
            drawnow; % Renders the change in text

            app.yAmpProcessed = timeToFrequency(app, app.yProcessed); % Calls the script used to calculate the frequency spectrum of the initial and processed audio

            app.StatusLabel.Text = "Plotting data..."; % Updates the status display
            drawnow; % Renders the change in text

            plot(app.ProcessedWaveform, app.t, app.yProcessed); % Plots the amplitudes over time of the processed sample matrix (Time domain)
            legend(app.ProcessedWaveform, app.legendText); % Adds a legend to the processed waveform plot
            app.ProcessedWaveform.YLim = app.InitialWaveform.YLim; % Limits the y axis of the plot to the same amplitude as the initial waveform to visualise the change in amplitude
            plot(app.ProcessedFreqSpectrum, app.xFreq, app.yAmpProcessed, LineWidth=2); % Plots the frequency specrum of the processed sample matrix (Frequency domain)
            hold(app.ProcessedFreqSpectrum, "on"); % Turns on hold for the plot so that another line can be added without clearing the previous line
            plot(app.ProcessedFreqSpectrum, app.wResponse, abs(app.H)*app.InitialFreqSpectrum.YLim(2), 'color', 'red', LineWidth=2); % Plots the amplitude response of the generated filter on the same axes as the processed frequency spectrum for comparison
            hold(app.ProcessedFreqSpectrum, "off"); % Turns off hold for the plot so that future lines added clear the previous lines
            legend(app.ProcessedFreqSpectrum, [app.legendText; "Filter Amplitude Response"]);

            xlim(app.ProcessedFreqSpectrum, [0, 20000]); % Limits the range of the x axis
            app.ProcessedFreqSpectrum.YLim = app.InitialFreqSpectrum.YLim; % Limits the y axis of the plot to the same amplitude as the initial frequency spectrum to visualise the change in amplitude

            app.FilterTypeDisplay.Text = app.filterType; % Updates filter type in the filter information panel
            if ismember(app.filterType, ['Low Pass', 'High Pass']) % If the filter is a low or high pass, the following code will execute
                app.CutoffFrequencyDisplay.Text = num2str(app.fcUser); % Updates cutoff frequency in the filter information panel
            else % If the filter is a band pass or band stop filter, the following code will execute
                app.CutoffFrequencyDisplay.Text = [num2str(app.fcUser-app.bandwidth/2) ' , ' num2str(app.fcUser+app.bandwidth/2)]; % Updates cutoff frequency in the filter information panel
            end % ends the if statement

            app.WindowSizeDisplay.Text = num2str(app.ws); % Updates window size in the filter information panel
            app.WindowFunctionDisplay.Text = app.wType; % Updates window function in the filter information panel
            updateFilterFigures(app); % Updates the UIAxes associated with displaying the filter information

            app.disableWhileProcessing(0); % Reenables user editable UI elements

            app.StatusLabel.Text = "Filter applied successfully!"; % Updates the status display
            app.ToggleFilterButton.Enable = 'on'; % Enables the toggle filter button
            drawnow; % Renders the change in text
            pause(5); % Waits 5 seconds
            app.StatusLabel.Text = "Currently idle."; % Updates the status display with the name of the file
            
        end

        % Value changed function: WindowSizeField
        function WindowSizeFieldValueChanged(app, event)
            if rem(app.WindowSizeField.Value, 2) ~=0 && app.WindowSizeField.Value > 2 && app.WindowSizeField.Value < 1002 % If the value entered is odd and greater than 2 but less than 1002, the following code will execute
                app.ws = app.WindowSizeField.Value; % Sets the window size variable to the user input window size
            else % If the value entered by the user is even or too small, the following code will execute
                app.WindowSizeField.Value = app.ws; % Sets the field back to the previously accepted window size value
            end % Ends the if statement
        end

        % Value changed function: WindowFunctionDropdown
        function WindowFunctionDropdownValueChanged(app, event)
            app.wType = app.WindowFunctionDropdown.Value; % Sets the filter type variable to the currently selected entry
        end

        % Value changed function: FrequencyField
        function FrequencyFieldValueChanged(app, event)
            if app.FrequencyField.Value > 0 && app.FrequencyField.Value < 20000 % If the value entered is between 0-20000, the following code will execute
                app.fcUser = app.FrequencyField.Value; % Sets the cutoff frequency variable to the user input
                app.FrequencySlider.Value = app.fcUser; % Sets the frequency slider to the currently selected value
            else % If the value entered by the user outside this range, the following code will execute
                 app.FrequencyField.Value = app.fcUser; % Sets the field back to the previously accepted cutoff frequency
            end % Ends the if statement
        end

        % Value changed function: FilterTypeDropdown
        function FilterTypeDropdownValueChanged(app, event)
            app.filterType = app.FilterTypeDropdown.Value; % Sets the bandwidth variable to the user input
            if ismember(app.FilterTypeDropdown.Value, ["Band Pass", "Band Stop"]) % If the user selected band pass or band stop, the following code will execute
                app.BandwidthField.Enable = "on"; % Enables the bandwidth entry field
                app.BandwidthSlider.Enable = "on"; % Enables the bandwidth knob
                app.CutoffFrequencyLabel.Text = "Cuttoff Frequency (Lower/Upper)"; % Changes the cutoff frequency label text
            else % If another option is selected, the following code will execute
                app.BandwidthField.Enable = "off"; % Disables the bandwidth entry field
                app.BandwidthSlider.Enable = "off"; % Disables the bandwidth knob
                app.CutoffFrequencyLabel.Text = "Cuttoff Frequency"; % Changes the cutoff frequency label text
            end % Ends the if statement
        end

        % Value changing function: WindowSizeSlider
        function WindowSizeSliderValueChanging(app, event)
            app.WindowSizeField.Value = floor(event.Value)*2+1; % Displays the currently selected value in the window size field
        end

        % Value changed function: WindowSizeSlider
        function WindowSizeSliderValueChanged(app, event)
            app.ws = floor(event.Value)*2+1; % Sets the ws variable to the currently selected value
        end

        % Value changed function: BandwidthField
        function BandwidthFieldValueChanged(app, event)
            if app.BandwidthField.Value >= 1 && app.BandwidthField.Value <= 500 % If the value entered is between 1-500 inclusive, the following code will execute
                app.bandwidth = app.BandwidthField.Value; % Sets the cutoff frequency variable to the user input
            else % If the value entered by the user outside this range, the following code will execute
                 app.BandwidthField.Value = app.bandwidth; % Sets the field back to the previously accepted cutoff frequency
            end % Ends the if statement
        end

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)
            app.FrequencyField.Value = event.Value; % Displays the currently selected value in the cutoff frequency field
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            app.fcUser = app.FrequencySlider.Value; % Sets the cutoff frequency variable to the currently selected value
        end

        % Value changed function: BandwidthSlider
        function BandwidthSliderValueChanged(app, event)
            app.bandwidth = floor(app.BandwidthSlider.Value); % Sets the bandwidth variable to the currently selected value
        end

        % Value changing function: BandwidthSlider
        function BandwidthSliderValueChanging(app, event)
            app.BandwidthField.Value = floor(event.Value); % Displays the currently selected value in the bandwidth field
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopAll = 1; % Sets the stop variable to 1 which stops playback and plots in other callbacks
        end

        % Value changed function: BufferSizeDropDown
        function BufferSizeDropDownValueChanged(app, event)
            app.bufferSize = str2double(app.BufferSizeDropDown.Value); % Sets the buffer size variable to the value selected
        end

        % Value changed function: timeSlider
        function timeSliderValueChanged(app, event)
            if app.timeSlider.Value > 1 % If the value of the slider is greater than 1, the following code will execute
                app.timeCode = floor(app.timeSlider.Value*app.fs); % Sets the time-code variable to the sample associated with the time currently selected
            else % If the value of the slider is below 1, the following code will execute
                app.timeCode = 1; % Sets the time-code variable to 1 (index for the first sample) as floor of any value below 1 will be 0 which is an invalid array index
            end % Ends the if statement
        end

        % Value changing function: timeSlider
        function timeSliderValueChanging(app, event)
            app.stopAll = 1; % Sets the stop variable to 1 which stops playback and plots in other callbacks
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % The following must be disabled so that the user cannot change
            % the variables being plotted as the plots are running as this
            % would crash the program:
            app.PlayButton.Enable = "off"; % Disables the play button
            app.BufferSizeDropDown.Enable = "off"; % Disables the buffer size dropdown
            app.ApplyFilterButton.Enable = "off"; % Disables the apply filter button
            drawnow; % Force renders the changes in the GUI
            
            % The frequency spectra must be pre-generated to reduce the
            % processing time between frames to ensure the playback occurs
            % in real time
            app.yRTAmpInit = app.frequencyBufferAnalysis(app.y); % Calls function to pre-generate the fourier transform of the buffers in the initial audio file and stores them in a matrix
            app.yRTAmpProcessed = app.frequencyBufferAnalysis(app.yProcessed); % Calls function to pre-generate the fourier transform of the buffers in the processed audio file and stores them in a matrix

            if app.ToggleFilterButton.Value == 0 % If the toggle filter button is in the off position, the following code will execute
                sound(app.y(app.timeCode:end, :),app.fs); % Starts playing the unprocessed audio from the sample last played/selected
            else % If the toggle filter button is in the on position, the following code will execute
                sound(app.yProcessed(app.timeCode:end, :),app.fs); % Starts playing the processed audio from the sample last played/selected
            end % Ends the if statement

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % To plot in real time, the processing time must be taken into
            % account as the time between frames must remain constant. From
            % computer to computer and even from command to command this
            % time will vary and thus a timer is used to measure this at
            % every frame generated. This is then subtracted from the
            % theoretical frametime to obtain the true delay duration
            % required. There may be instances where a processor is too
            % slow to complete the functions in the frame time required and
            % in these cases, frames should be skipped as a negative delay
            % would crash the program.
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.stopAll = 0; % Sets the binary check variable to false
            for i = app.timeCode:app.bufferSize:length(app.y)-app.bufferSize % The following code will execute for the length of the audio file
                if app.stopAll == 0 % If the user has not pressed the stop button, the following code will execute
                    processingTimeStart = tic(); % Starts a timer to measure the processing time of the following functions
                    if app.ToggleFilterButton.Value == 0 % If the toggle filter button is in the off position, the following code will execute
                        app.lissajousPlotter(i, app.y); % Calls function to plot the lissajous figure of the current buffer
                        app.realTimeFreqPlotter(i, app.yRTAmpInit); % Calls function to plot the real time frequency response of the audio at the current buffer
                    else % If the toggle filter button is in the on position, the following code will execute
                        app.lissajousPlotter(i, app.yProcessed); % Calls function to plot the lissajous figure of the current buffer
                        app.realTimeFreqPlotter(i, app.yRTAmpProcessed); % Calls function to plot the real time frequency response of the audio at the current buffer
                    end % Ends the if statement
                    
                    app.timeSlider.Value = i/app.fs; % Sets the time slider position to the current sample time
                    app.timeCode = app.timeCode+app.bufferSize; % Updates the last played sample variable
                    processingTime = toc(processingTimeStart); % Stops the timer measuring processing time
                    if processingTime < app.bufferSize/app.fs % If the time taken to process the plots is lower than the frametime, the following code will execute
                        pause(app.bufferSize/app.fs-processingTime); % Waits for the time of the buffer to complete before plotting the next buffer
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % If the processing time exceeds the time between
                    % frames, there should be a method to interrupt the
                    % callbacks and skip the frame here, however without
                    % multithreading I was unable to implement this feature
                    % and the plot will move out of sync in this case. This
                    % can be mitigated by increasing the buffer size to
                    % increase the frametime.
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    end % Ends the if statement
                else % If the user presses the stop button, the following code will execute
                    clear sound; % Clears the data in sound and stops playing
                    app.PlayButton.Enable = "on"; % Re-enables the play button
                    app.BufferSizeDropDown.Enable = "on"; % Re-enables the buffer size drop down
                    app.ApplyFilterButton.Enable = "on"; % Re-enables the apply filter button
                    return % Exits the callback
                end % Ends the if statement
            end % Ends the for loop

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % If this point is reached, the audio file has reached the end
            % of its duration.
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.stopAll = 1; % Sets the binary check variable back to 1 as
            clear sound; % Clears the data in sound and stops playing
            app.PlayButton.Enable = "on"; % Re-enables the play button
            app.BufferSizeDropDown.Enable = "on"; % Re-enables the buffer size drop down
            app.ApplyFilterButton.Enable = "on"; % Re-enables the apply filter button
            app.timeCode = 1; % Resets the variable to store the currently playing sample index to the start of the audio file
        end

        % Value changed function: ToggleFilterButton
        function ToggleFilterButtonValueChanged(app, event)
            clear sound; % Clears the data in sound and stops playing
            if app.ToggleFilterButton.Value == 0 % If the toggle filter button is in the off position, the following code will execute
                if app.stopAll == 0 % If the binary check variable is false (playback is currently occurring), the following code will execute
                    sound(app.y(app.timeCode:end, :),app.fs); % Starts playing the unfiltered file from the last played sample
                end % Ends the if statement
                app.SignalType.Text = "Unfiltered Signal"; % Updates the currently playing label
            else % If the toggle filter button is in the on position, the following code will execute
                if app.stopAll == 0 % If the binary check variable is false (playback is currently occurring), the following code will execute
                    sound(app.yProcessed(app.timeCode:end, :),app.fs); % Starts playing the filtered file from the last played sample
                end % Ends the if statement
                app.SignalType.Text = "Filtered Signal"; % Updates the currently playing label
            end % Ends the if statement
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImportFileButton = uibutton(app.GridLayout, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.SignalProcessorLabel = uilabel(app.GridLayout);
            app.SignalProcessorLabel.Text = 'Signal Processor';
            app.PlaybackPanel = uipanel(app.GridLayout);
            app.PlaybackPanel.Title = 'Playback';
            app.GridLayout2 = uigridlayout(app.PlaybackPanel);
            app.timeSlider = uislider(app.GridLayout2);
            app.timeSlider.MajorTickLabels = {''};
            app.timeSlider.ValueChangedFcn = createCallbackFcn(app, @timeSliderValueChanged, true);
            app.timeSlider.ValueChangingFcn = createCallbackFcn(app, @timeSliderValueChanging, true);
            app.timeSlider.Enable = 'off';
            app.StopButton = uibutton(app.GridLayout2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.PlayButton = uibutton(app.GridLayout2, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = 'Play';
            app.CurrentlyPlayingLabel = uilabel(app.GridLayout2);
            app.CurrentlyPlayingLabel.Text = 'Currently Playing :';
            app.SignalType = uilabel(app.GridLayout2);
            app.SignalType.Text = 'Unfiltered Signal';
            app.ToggleFilterButton = uibutton(app.GridLayout2, 'state');
            app.ToggleFilterButton.ValueChangedFcn = createCallbackFcn(app, @ToggleFilterButtonValueChanged, true);
            app.ToggleFilterButton.Enable = 'off';
            app.ToggleFilterButton.Text = 'Toggle Filter';
            app.FilterPanel = uipanel(app.GridLayout);
            app.FilterPanel.Title = 'Filter';
            app.GridLayout3 = uigridlayout(app.FilterPanel);
            app.AmplitudeResponse = uiaxes(app.GridLayout3);
            title(app.AmplitudeResponse, 'Filter Amplitude Response')
            xlabel(app.AmplitudeResponse, 'Frequency / Hz')
            ylabel(app.AmplitudeResponse, 'Amplitude')
            zlabel(app.AmplitudeResponse, 'Z')
            app.AmplitudeResponse.XLim = [20 20000];
            app.CutoffFrequencyHzLabel = uilabel(app.GridLayout3);
            app.CutoffFrequencyHzLabel.Text = 'Cutoff Frequency / Hz';
            app.FrequencyField = uieditfield(app.GridLayout3, 'numeric');
            app.FrequencyField.ValueChangedFcn = createCallbackFcn(app, @FrequencyFieldValueChanged, true);
            app.FrequencyField.Enable = 'off';
            app.FrequencyField.Value = 10000;
            app.WindowSizeLabel = uilabel(app.GridLayout3);
            app.WindowSizeLabel.Text = 'Window Size';
            app.FilterTypeLabel = uilabel(app.GridLayout3);
            app.FilterTypeLabel.Text = 'Filter Type';
            app.BandwidthHzLabel = uilabel(app.GridLayout3);
            app.BandwidthHzLabel.Text = 'Bandwidth / Hz';
            app.BandwidthField = uieditfield(app.GridLayout3, 'numeric');
            app.BandwidthField.ValueChangedFcn = createCallbackFcn(app, @BandwidthFieldValueChanged, true);
            app.BandwidthField.Enable = 'off';
            app.BandwidthField.Value = 100;
            app.FilterTypeDropdown = uidropdown(app.GridLayout3);
            app.FilterTypeDropdown.Items = {'Low Pass', 'High Pass', 'Band Pass', 'Band Stop'};
            app.FilterTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropdownValueChanged, true);
            app.FilterTypeDropdown.Enable = 'off';
            app.FilterTypeDropdown.Value = 'Low Pass';
            app.WindowSizeField = uieditfield(app.GridLayout3, 'numeric');
            app.WindowSizeField.ValueChangedFcn = createCallbackFcn(app, @WindowSizeFieldValueChanged, true);
            app.WindowSizeField.Enable = 'off';
            app.WindowSizeField.Value = 35;
            app.WindowFunctionLabel = uilabel(app.GridLayout3);
            app.WindowFunctionLabel.Text = 'Window Function';
            app.WindowFunctionDropdown = uidropdown(app.GridLayout3);
            app.WindowFunctionDropdown.Items = {'Hanning', 'Hamming', 'Blackman'};
            app.WindowFunctionDropdown.ValueChangedFcn = createCallbackFcn(app, @WindowFunctionDropdownValueChanged, true);
            app.WindowFunctionDropdown.Enable = 'off';
            app.WindowFunctionDropdown.Value = 'Hanning';
            app.WindowSizeSlider = uislider(app.GridLayout3);
            app.WindowSizeSlider.Limits = [3 50];
            app.WindowSizeSlider.MajorTicks = [];
            app.WindowSizeSlider.MajorTickLabels = {''};
            app.WindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSizeSliderValueChanged, true);
            app.WindowSizeSlider.ValueChangingFcn = createCallbackFcn(app, @WindowSizeSliderValueChanging, true);
            app.WindowSizeSlider.MinorTicks = [];
            app.WindowSizeSlider.Enable = 'off';
            app.WindowSizeSlider.Value = 17;
            app.ApplyFilterButton = uibutton(app.GridLayout3, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Enable = 'off';
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.FrequencySlider = uislider(app.GridLayout3);
            app.FrequencySlider.Limits = [0 20000];
            app.FrequencySlider.MajorTicks = [0 20000];
            app.FrequencySlider.MajorTickLabels = {'0', '20000'};
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.FrequencySlider.MinorTicks = [0 1000 2000 3000 4000 5000 6000 7000 8000 9000 10000 11000 12000 13000 14000 15000 16000 17000 18000 19000 20000];
            app.FrequencySlider.Enable = 'off';
            app.FrequencySlider.Value = 10000;
            app.BandwidthSlider = uislider(app.GridLayout3);
            app.BandwidthSlider.Limits = [1 10000];
            app.BandwidthSlider.MajorTicks = [1 10000];
            app.BandwidthSlider.MajorTickLabels = {'1', '10000'};
            app.BandwidthSlider.ValueChangedFcn = createCallbackFcn(app, @BandwidthSliderValueChanged, true);
            app.BandwidthSlider.ValueChangingFcn = createCallbackFcn(app, @BandwidthSliderValueChanging, true);
            app.BandwidthSlider.MinorTicks = [0 1000 2000 3000 4000 5000 6000 7000 8000 9000 10000 11000 12000 13000 14000 15000 16000 17000 18000 19000 20000];
            app.BandwidthSlider.Enable = 'off';
            app.BandwidthSlider.Value = 100;
            app.TabGroup = uitabgroup(app.GridLayout);
            app.AudioFileTab = uitab(app.TabGroup);
            app.AudioFileTab.Title = 'Audio File';
            app.GridLayout6 = uigridlayout(app.AudioFileTab);
            app.InitialWaveform = uiaxes(app.GridLayout6);
            title(app.InitialWaveform, 'Unprocessed Waveform')
            xlabel(app.InitialWaveform, 'Time /s')
            ylabel(app.InitialWaveform, 'Amplitude')
            zlabel(app.InitialWaveform, 'Z')
            app.InitialFreqSpectrum = uiaxes(app.GridLayout6);
            title(app.InitialFreqSpectrum, 'Unprocessed Frequency Spectrum')
            xlabel(app.InitialFreqSpectrum, 'Frequency /Hz')
            ylabel(app.InitialFreqSpectrum, 'Amplitude')
            zlabel(app.InitialFreqSpectrum, 'Time /s')
            app.ProcessedFreqSpectrum = uiaxes(app.GridLayout6);
            title(app.ProcessedFreqSpectrum, 'Processed Frequency Spectrum')
            xlabel(app.ProcessedFreqSpectrum, 'Frequency /Hz')
            ylabel(app.ProcessedFreqSpectrum, 'Amplitude')
            zlabel(app.ProcessedFreqSpectrum, 'Time /s')
            app.ProcessedWaveform = uiaxes(app.GridLayout6);
            title(app.ProcessedWaveform, 'Processed Waveform')
            xlabel(app.ProcessedWaveform, 'Time /s')
            ylabel(app.ProcessedWaveform, 'Amplitude')
            zlabel(app.ProcessedWaveform, 'Z')
            app.FileInformationPanel = uipanel(app.GridLayout6);
            app.FileInformationPanel.Title = 'File Information';
            app.GridLayout2_2 = uigridlayout(app.FileInformationPanel);
            app.FilePathLabel = uilabel(app.GridLayout2_2);
            app.FilePathLabel.Text = 'File Path';
            app.NumberofChannelsLabel = uilabel(app.GridLayout2_2);
            app.NumberofChannelsLabel.Text = 'Number of Channels';
            app.SampleRateLabel = uilabel(app.GridLayout2_2);
            app.SampleRateLabel.Text = 'Sample Rate';
            app.DurationLabel = uilabel(app.GridLayout2_2);
            app.DurationLabel.Text = 'Duration';
            app.FilePathDisplay = uilabel(app.GridLayout2_2);
            app.FilePathDisplay.Text = 'N/A';
            app.ChannelNumberDisplay = uilabel(app.GridLayout2_2);
            app.ChannelNumberDisplay.Text = 'N/A';
            app.SampleRateDisplay = uilabel(app.GridLayout2_2);
            app.SampleRateDisplay.Text = 'N/A';
            app.DurationDisplay = uilabel(app.GridLayout2_2);
            app.DurationDisplay.Text = 'N/A';
            app.FilterAttributesTab = uitab(app.TabGroup);
            app.FilterAttributesTab.Title = 'Filter Attributes';
            app.GridLayout7 = uigridlayout(app.FilterAttributesTab);
            app.AmplitudeResponseLarge = uiaxes(app.GridLayout7);
            title(app.AmplitudeResponseLarge, 'Filter Amplitude Response')
            xlabel(app.AmplitudeResponseLarge, 'Frequency / Hz')
            ylabel(app.AmplitudeResponseLarge, 'Amplitude')
            zlabel(app.AmplitudeResponseLarge, 'Z')
            app.AmplitudeResponseLarge.XLim = [20 20000];
            app.PhaseResponse = uiaxes(app.GridLayout7);
            title(app.PhaseResponse, 'Filter Phase Response')
            xlabel(app.PhaseResponse, 'Frequency / Hz')
            ylabel(app.PhaseResponse, 'Phase / Radians')
            zlabel(app.PhaseResponse, 'Z')
            app.PhaseResponse.XLim = [20 20000];
            app.FilterCoefficients = uiaxes(app.GridLayout7);
            title(app.FilterCoefficients, 'Filter Coefficients')
            zlabel(app.FilterCoefficients, 'Z')
            app.FilterCoefficients.XLim = [20 20000];
            app.BasicFilterResponse = uiaxes(app.GridLayout7);
            title(app.BasicFilterResponse, 'Basic Filter Response')
            zlabel(app.BasicFilterResponse, 'Z')
            app.BasicFilterResponse.XLim = [20 20000];
            app.FilterInformationPanel = uipanel(app.GridLayout7);
            app.FilterInformationPanel.Title = 'Filter Information';
            app.GridLayout2_3 = uigridlayout(app.FilterInformationPanel);
            app.CutoffFrequencyLabel = uilabel(app.GridLayout2_3);
            app.CutoffFrequencyLabel.Text = 'Cutoff Frequency';
            app.WindowSizeLabel_2 = uilabel(app.GridLayout2_3);
            app.WindowSizeLabel_2.Text = 'Window Size';
            app.WindowFunctionLabel_2 = uilabel(app.GridLayout2_3);
            app.WindowFunctionLabel_2.Text = 'Window Function';
            app.FilterTypeLabel_2 = uilabel(app.GridLayout2_3);
            app.FilterTypeLabel_2.Text = 'Filter Type';
            app.FilterTypeDisplay = uilabel(app.GridLayout2_3);
            app.FilterTypeDisplay.Text = 'N/A';
            app.CutoffFrequencyDisplay = uilabel(app.GridLayout2_3);
            app.CutoffFrequencyDisplay.Text = 'N/A';
            app.WindowSizeDisplay = uilabel(app.GridLayout2_3);
            app.WindowSizeDisplay.Text = 'N/A';
            app.WindowFunctionDisplay = uilabel(app.GridLayout2_3);
            app.WindowFunctionDisplay.Text = 'N/A';
            app.RealTimeMonitoringTab = uitab(app.TabGroup);
            app.RealTimeMonitoringTab.Title = 'Real Time Monitoring';
            app.GridLayout7_2 = uigridlayout(app.RealTimeMonitoringTab);
            app.RTFreqSpectrum = uiaxes(app.GridLayout7_2);
            title(app.RTFreqSpectrum, 'Frequency Spectrum')
            xlabel(app.RTFreqSpectrum, 'Frequency / Hz')
            ylabel(app.RTFreqSpectrum, 'Amplitude')
            zlabel(app.RTFreqSpectrum, 'Z')
            app.LissajousFigure = uiaxes(app.GridLayout7_2);
            title(app.LissajousFigure, 'Lissajous Pattern')
            xlabel(app.LissajousFigure, 'Right')
            ylabel(app.LissajousFigure, 'Left')
            zlabel(app.LissajousFigure, 'Z')
            app.InformationPanel = uipanel(app.GridLayout7_2);
            app.InformationPanel.Title = 'Information';
            app.GridLayout8 = uigridlayout(app.InformationPanel);
            app.BufferSizeDropDownLabel = uilabel(app.GridLayout8);
            app.BufferSizeDropDownLabel.Text = 'Buffer Size';
            app.BufferSizeDropDown = uidropdown(app.GridLayout8);
            app.BufferSizeDropDown.Items = {'2048', '4096', '8192', '16384', '32768'};
            app.BufferSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @BufferSizeDropDownValueChanged, true);
            app.BufferSizeDropDown.Value = '4096';
            app.Label = uilabel(app.GridLayout8);
            app.Label.Text = 'Values for buffer size below 4096 may be unstable on slower machines. If the figures are drifting out of sync, please try increasing the buffer size.';
            app.TheLissajousplotshowsthephasedifferencebetweentwochannelsLabel = uilabel(app.GridLayout8);
            app.TheLissajousplotshowsthephasedifferencebetweentwochannelsLabel.Text = 'The Lissajous pattern visualises the phase difference between two channels by plotting the amplitudes of the same sample number from each channel as a point. The more the points deviate from the central y = x line, the greater the difference. The plot will only show a straight line of y = x for mono files.';
            app.ExportAudioButton = uibutton(app.GridLayout, 'push');
            app.ExportAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAudioButtonPushed, true);
            app.ExportAudioButton.Enable = 'off';
            app.ExportAudioButton.Text = 'Export Audio';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Import a file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalProcessor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end