classdef SignalProcessorV003 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        ShowTimedimensionButton  matlab.ui.control.StateButton
        HPKnob                   matlab.ui.control.Knob
        HPKnobLabel              matlab.ui.control.Label
        BPKnob                   matlab.ui.control.Knob
        BPKnobLabel              matlab.ui.control.Label
        LPkHzKnobLabel           matlab.ui.control.Label
        LPkHzKnob                matlab.ui.control.Knob
        Gauge_2                  matlab.ui.control.LinearGauge
        Gauge                    matlab.ui.control.LinearGauge
        BufferSizeDropDown       matlab.ui.control.DropDown
        BufferSizeDropDownLabel  matlab.ui.control.Label
        SignalProcessorLabel     matlab.ui.control.Label
        ImportFileButton         matlab.ui.control.Button
        frequencySpectrum        matlab.ui.control.UIAxes
        waveform                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fullPath = "";
        bufferSize = 4096; % Sets the number of samples to be transformed via discrete fourier transform per iteration. A higher number means the transform will be taken from a larger time period so will be a more averaged representation of the sample, however a lower buffer size will give a more precise representation of the frequencies present at a given point in time of a signal which changes often and quickly
        player; % initialises audio player object

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % The minimum buffer size can be obtained by using the formula 
        % frequency = sample rate / number of samples taken
        % to find the minimum frequency required. The minimum frequency
        % humans can hear is 20hz. Using a standard 44.1k audio file,
        % the minimum buffer size is 2048 (21.5Hz). For best results,
        % however, the buffer size should be 2-3 times as large as the
        % smallest frequency. As a result, the default buffer size is set
        % to 4096 samples. A larger buffer size is less ideal as fewer
        % frames are generated so the plot is less smooth, however a more
        % accurate plot is more advantageous than inaccurate realtime data.
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        timeFrequencySpectrumView = true;
        
    end
        
    methods (Access = private)
        
        function [] = drawCurve(app, xPos)
            cla(app.UIAxes);
            line = animatedline('parent', app.UIAxes);
            clearpoints(line);
            addpoints(line, [xPos, xPos], [0, 1]);
            drawnow limitrate nocallbacks;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            [file,path] = uigetfile('*.wav','Select a .wav file'); % Prompts user to select a file for input and stores the filename and path as variables
            app.fullPath = append(path, file); % Converts the filename and path to a single string to be read by the audioread function
            % add file validation routine
            waveformY = audioread(app.fullPath); % Stores amplitude data in an array
            colororder(app.waveform, {'c', 'm'}); 
            app.waveform.Color = 'k';
            plot(app.waveform, waveformY); % Plots the data on the axes to display the waveform of the file
            app.waveform.Title.String = file;

            [samples, sampleRate] = audioread(app.fullPath); % Obtains the samples and sample rate in Hz from the audio file imported
            app.bufferSize = (sampleRate/20)*2; % Alters buffer size to optimal for the sample rate imported, user may manually change this using the dropdown.
            

            app.frequencySpectrum.XGrid = "on"; % Turns on the grid for the x axis
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % debug console stuff
            disp("duration: ") 
            disp(app.bufferSize/sampleRate*length(1:app.bufferSize:(length(samples)-app.bufferSize)));
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % Due to the symmetrical pattern formed from the real numbers 
            % in the fft, the frequency domain must be halved.
            amplitudesYReal = [zeros(1,app.bufferSize/2+1)]; % Initialises matrix for storing y values
            frequenciesX = sampleRate/2*linspace(0,1,app.bufferSize/2+1); % Creates an array of frequencies for the x axis

            
            app.player = audioplayer(samples, sampleRate); % Creates an object in the private class containing the samples of the audio file and sample rate so that when the function ends the object is not deleted and audio does not stop
            play(app.player); % Plays the samples from the audio file at the specified sample rate

            % Previously, the plot function was used however this was too
            % inefficient to plot the curve in real time. This lead to the
            % curve drifting out of sync with the audio file as time
            % progressed. To mitigate this, an animated line is used and
            % the drawnow update option ensures that if the render falls
            % behind, the frame will be skipped to retain synchronisation.
            % limitrate is not used as this limits to 20fps.

            frequencyCurve = animatedline('parent', app.frequencySpectrum); % Creates an animated line for the frequency spectrum curve.
            set(app.frequencySpectrum, 'ylim',[-100 100],'XScale', 'log', 'xlim', [20 20000], 'zlim', [-length(samples)/app.bufferSize, 0]); % Sets the frequency spectrum axes properties
           
            % log scale justification : the unit for amplitude in audio is
            % decibells (dB) which works from a logorithmic scale, however 
            % the scale should be linear as the amplitude has already been 
            % converted into a logorithm as it is in decibels. If it were 
            % logorithmic, the y axis would be scaled twice. 
            % The frequency spectrum is displaye as logorithmic due to the 
            % nature of the relationships of notes and their respective 
            % frequencies. When a note increases pitch by an octave, 
            % frequency doubles. A logorithmic scale is therefore used to 
            % maintain the distance between peaks and represent pitch in a 
            % more readable way.

            % ylim justification : as buffer size increases, the fft 
            % function takes more samples and the amplitude increases as a 
            % result. To maintain the scale at different buffer sizes, the 
            % y axis must have an adaptive scale. Modelling a sample as the 
            % average of the maximum and minimum amplitude of a sine wave 
            % gives 0.5 and then multiplying this by the number of samples 
            % in the buffer size will provide an adequate estimate for the 
            % maximum scale.

            % xlim justification : the maximum range of frequencies humans 
            % can detect is from 20-20000Hz, therefore plotting beyond 
            % these values is not necessary.
            z = zeros([1 app.bufferSize/2+1]);

            for i = 1:app.bufferSize:(length(samples)-app.bufferSize)
                z = z-1;
                sampleTake = samples(i:(i+app.bufferSize-1)); % Takes bufferSize number of samples from the audio file at point i in the file. This splits the file into many parts to be analysed individually
                amplitudesY = fft(sampleTake, app.bufferSize); % Creates an array of amplitudes corrosponding to the discrete fourier transform of the imported file. This gives a spectrum of the frequencies present in the signal in the frequency domain with the Y axis corrosponding to their relative (convert to absolute in dB?) intensities
                % adjust for DC error here
                amplitudesYReal = [amplitudesYReal; 20*log10(abs(amplitudesY(1:app.bufferSize/2+1)*2))]; % Adds the real values of amplitudesY to a matrix.The result must be multiplied by 2 because the symmetrical fourier transform divides the energy between the two halves, therefore in order to accurately display the amplitude of the signal the result should be doubled. This is then logged and multiplied by 20 to convert to decibels.
                if app.timeFrequencySpectrumView == false
                    clearpoints(frequencyCurve); % Clears the previously plotted points
                end
                addpoints(frequencyCurve, frequenciesX, amplitudesYReal(end, 1:end), z(1,1:end)); % Adds the amplitudes of the frequencies obtained from the fourier transform of the latest buffer alongside the respective frequencies
                drawnow update; % Renders the new line, skips if the renderer is busy from the previous plot to maintain synchronisation
                pause(app.bufferSize/sampleRate); % As f = 1/t, t = 1/f, multiply time by number of samples taken to obtain correct frametime and this allows the graph to be plot in realtime
            end
        end

        % Value changed function: BufferSizeDropDown
        function BufferSizeDropDownValueChanged(app, event)
            value = str2double(app.BufferSizeDropDown.Value);
            app.bufferSize = value;
        end

        % Value changed function: LPkHzKnob
        function LPkHzKnobValueChanged(app, event)
            value = app.LPkHzKnob.Value;
            
        end

        % Value changing function: LPkHzKnob
        function LPkHzKnobValueChanging(app, event)
            value = event.Value;
            app.drawCurve(1000*value);
            hold(app.UIAxes, 'off');
            
        end

        % Value changed function: ShowTimedimensionButton
        function ShowTimedimensionButtonValueChanged(app, event)
            value = app.ShowTimedimensionButton.Value;
            app.timeFrequencySpectrumView = ~app.timeFrequencySpectrumView;
            if app.timeFrequencySpectrumView == true
                app.frequencySpectrum.View = [0 -20];
            else
                app.frequencySpectrum.View = [0 90];
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Filter Curve')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [20 20000];
            app.waveform = uiaxes(app.GridLayout);
            title(app.waveform, '[ Import a file ]')
            xlabel(app.waveform, 'Time /s')
            ylabel(app.waveform, 'Amplitude /dB')
            zlabel(app.waveform, 'Z')
            app.frequencySpectrum = uiaxes(app.GridLayout);
            title(app.frequencySpectrum, 'Frequency Spectrum')
            xlabel(app.frequencySpectrum, 'Frequency /Hz')
            ylabel(app.frequencySpectrum, 'Amplitude /dB')
            zlabel(app.frequencySpectrum, 'Time /s')
            app.ImportFileButton = uibutton(app.GridLayout, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.SignalProcessorLabel = uilabel(app.GridLayout);
            app.SignalProcessorLabel.Text = 'Signal Processor';
            app.BufferSizeDropDownLabel = uilabel(app.GridLayout);
            app.BufferSizeDropDownLabel.Text = 'Buffer Size';
            app.BufferSizeDropDown = uidropdown(app.GridLayout);
            app.BufferSizeDropDown.Items = {'4096', '8192', '16384', '32768'};
            app.BufferSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @BufferSizeDropDownValueChanged, true);
            app.BufferSizeDropDown.Value = '4096';
            app.Gauge = uigauge(app.GridLayout, 'linear');
            app.Gauge_2 = uigauge(app.GridLayout, 'linear');
            app.LPkHzKnob = uiknob(app.GridLayout, 'continuous');
            app.LPkHzKnob.Limits = [0 20];
            app.LPkHzKnob.ValueChangedFcn = createCallbackFcn(app, @LPkHzKnobValueChanged, true);
            app.LPkHzKnob.ValueChangingFcn = createCallbackFcn(app, @LPkHzKnobValueChanging, true);
            app.LPkHzKnob.Value = 20;
            app.LPkHzKnobLabel = uilabel(app.GridLayout);
            app.LPkHzKnobLabel.Text = 'LP /kHz';
            app.BPKnobLabel = uilabel(app.GridLayout);
            app.BPKnobLabel.Text = 'BP';
            app.BPKnob = uiknob(app.GridLayout, 'continuous');
            app.HPKnobLabel = uilabel(app.GridLayout);
            app.HPKnobLabel.Text = 'HP';
            app.HPKnob = uiknob(app.GridLayout, 'continuous');
            app.ShowTimedimensionButton = uibutton(app.GridLayout, 'state');
            app.ShowTimedimensionButton.ValueChangedFcn = createCallbackFcn(app, @ShowTimedimensionButtonValueChanged, true);
            app.ShowTimedimensionButton.Text = 'Show Time dimension';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalProcessorV003

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end